classdef optimal_thickness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AbsorberindexofrefractionEditFieldLabel  matlab.ui.control.Label
        AbsorberindexofrefractionEditField  matlab.ui.control.NumericEditField
        UnitsDropDownLabel        matlab.ui.control.Label
        UnitsDropDown             matlab.ui.control.DropDown
        ThicknessButton           matlab.ui.control.Button
        ThicknessEditField        matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel  matlab.ui.control.Label
        PhaseShiftEditField       matlab.ui.control.NumericEditField
        MaterialsDropDownLabel    matlab.ui.control.Label
        MaterialsDropDown         matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Parses the euv-materials spreadsheet into lists of materials, 
            % and index of refraction
            % materials: cell array
            % deltas, betas: regular arrays
            
            function [materials, deltas, betas] = parseIndexFromDataFile(path)
                
                [~, ~, raw] = xlsread(path, 'Sheet1', 'A1:C7');
                
                [Nr, Nc] = size(raw);
                counter = 0;
                materials = {};
                deltas = [];
                betas = [];

                for indexR = 1:Nr 
                    for indexC = 1:Nc      
                        counter = counter + 1;      
                        fprintf('indexC=%d, indexR=%d, counter=%d\n', indexC, indexR, counter);    
                                if (counter > 3 && mod(counter, 3) == 1)        
                                    % Then the value is a material but not 
                                    % the literal word "material"
                                    
                                    % add this to materials list          
                                    materials{end + 1} = raw{indexR, indexC};       
                                end      
                                if (counter > 3 && mod(counter, 3) == 2)       
                                    %Then, this is a "delta" but not the
                                    %label delta
                                    
                                    % add this to the deltas list
                                    deltas(end + 1) = raw{indexR, indexC};
                                end              
                                if (counter > 3 && mod(counter, 3) == 0)          
                                    %Then, this is a "beta" but not the label beta    
                                    
                                    % add this to the betas list          
                                    betas(end + 1) = raw{indexR, indexC};
       
                                end   
                    end
                end
            end
            
            [materials, ~, ~] = parseIndexFromDataFile('/Users/amberlyxie/Box/euv/optimal-phase-shift/data/euv-materials.xlsx');
            
            % Create MaterialsDropDownLabel
            app.MaterialsDropDownLabel = uilabel(app.UIFigure);
            app.MaterialsDropDownLabel.HorizontalAlignment = 'right';
            app.MaterialsDropDownLabel.Position = [125 206 54 22];
            app.MaterialsDropDownLabel.Text = 'Materials';

            % Create MaterialsDropDown
            app.MaterialsDropDown = uidropdown(app.UIFigure);
            app.MaterialsDropDown.Position = [194 206 100 22];
            app.MaterialsDropDown.Items = materials;
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % do nothing
        end

        % Button pushed function: ThicknessButton
        function ThicknessButtonPushed(app, event)
            
            % 1) ask what the phase shift value is
            phaseShift = app.PhaseShiftEditField.Value;
            
            % 2) figure out what the correct unit is, and compute
            % phaseshift in correct unit
            unit = app.UnitsDropDown.Value;
            
            if strcmp(unit, 'radians') 
                phaseShift = app.PhaseShiftEditField.Value;
            elseif strcmp(unit, 'degrees')
                phaseShift = app.PhaseShiftEditField.Value*pi/180;
            elseif strcmp(unit, 'waves')
                phaseShift = app.PhaseShiftEditField.Value*2*pi;
            end
            
            % 3) compute thickness using correct phase shift unit          
            n1 = 1; % vacuum
            lambda = 13.5; % nm
            theta1 = 6*pi/180; % 6 degrees in radians
            n2 = app.AbsorberindexofrefractionEditField.Value;
            theta2 = asin(n1/n2*sin(theta1));
            d = phaseShift*lambda/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
            
            % if d is negative, then we know it's too small and must find
            % the next solution that is positive
            counter = 0;
            while (d<0)
                phaseShift = phaseShift-(2*pi);
                d = phaseShift*lambda/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
                counter = counter+1;
                if (counter>50) % likely in an infinite loop
                    break
                end
            end
            
            % display optimal thickness
            app.ThicknessEditField.Value = d;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AbsorberindexofrefractionEditFieldLabel = uilabel(app.UIFigure);
            app.AbsorberindexofrefractionEditFieldLabel.Text = 'Absorber index of refraction';
            app.AbsorberindexofrefractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'radians', 'degrees', 'waves', ''};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'radians';
            app.ThicknessButton = uibutton(app.UIFigure, 'push');
            app.ThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ThicknessButtonPushed, true);
            app.ThicknessButton.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaterialsDropDownLabel = uilabel(app.UIFigure);
            app.MaterialsDropDownLabel.Text = 'Materials';
            app.MaterialsDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimal_thickness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end